classdef Unidad_procesamiento_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EntradaTab                      matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        CargardatosdeldispositivoButton  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        RecopilardatosButton            matlab.ui.control.Button
        DuracindelasealEditField        matlab.ui.control.NumericEditField
        DuracindelasealEditFieldLabel   matlab.ui.control.Label
        NohayconexinUARTLabel           matlab.ui.control.Label
        ConectarmicrocontroladorButton  matlab.ui.control.Button
        RepresentacindegrficaDropDown   matlab.ui.control.DropDown
        RepresentacindegrficaDropDownLabel  matlab.ui.control.Label
        graficaentrada                  matlab.ui.control.UIAxes
        espectroentrada                 matlab.ui.control.UIAxes
        FiltroTab                       matlab.ui.container.Tab
        GuardarvariablesdelfiltroButton  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FiltroIIRButton                 matlab.ui.control.RadioButton
        FiltroFIRButton                 matlab.ui.control.RadioButton
        RespuestaenfercuenciaLabel      matlab.ui.control.Label
        ExportaramicrocontroladorButton  matlab.ui.control.Button
        DiseodefiltroPanel              matlab.ui.container.Panel
        w2debesermayoraw1Label          matlab.ui.control.Label
        CategoraDropDown                matlab.ui.control.DropDown
        CategoraDropDownLabel           matlab.ui.control.Label
        Frecuenciadecortew2EditField    matlab.ui.control.NumericEditField
        Frecuenciadecortew2EditFieldLabel  matlab.ui.control.Label
        GenerarfiltroButton             matlab.ui.control.Button
        OrdenEditField                  matlab.ui.control.NumericEditField
        OrdenEditFieldLabel             matlab.ui.control.Label
        EscalahorizontalDropDown        matlab.ui.control.DropDown
        EscalahorizontalDropDownLabel   matlab.ui.control.Label
        EscalaverticalDropDown          matlab.ui.control.DropDown
        EscalaverticalDropDownLabel     matlab.ui.control.Label
        GraficarLabel                   matlab.ui.control.Label
        TipodefiltroDropDown_2          matlab.ui.control.DropDown
        TipodefiltroDropDown_2Label     matlab.ui.control.Label
        Frecuenciadecortew1EditField    matlab.ui.control.NumericEditField
        Frecuenciadecortew1EditFieldLabel  matlab.ui.control.Label
        fase                            matlab.ui.control.UIAxes
        magnitud                        matlab.ui.control.UIAxes
        SalidaTab                       matlab.ui.container.Tab
        RepresentacindegrficaDropDown_3  matlab.ui.control.DropDown
        RepresentacindegrficaDropDown_3Label  matlab.ui.control.Label
        RepresentacindegrficaDropDown_2  matlab.ui.control.DropDown
        RepresentacindegrficaDropDown_2Label  matlab.ui.control.Label
        ExportardatosButton             matlab.ui.control.Button
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        espectrooriginal2               matlab.ui.control.UIAxes
        graficaoriginal2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N
        T = 1/1000
        Tdur
        stemorplot = 0
        conexion_uart
        t
        datos
        Fs = 1000
        signal
        tipo_filtro = "low"
        metodo = "butter"
        b_func
        a_func
    end
    
    methods (Access = private)

        function inicializar(app) 
            app.DuracindelasealEditField.Visible = "off";
            app.DuracindelasealEditFieldLabel.Visible ="off";
            app.NohayconexinUARTLabel.Visible = "off";
            app.RecopilardatosButton.Visible = "off";
            % Inicializar en pestaña de filtro
            app.w2debesermayoraw1Label.Visible = "off";
            app.Frecuenciadecortew2EditField.Visible = "off";
            app.Frecuenciadecortew2EditFieldLabel.Visible = "off";

        end

        % VERIFICAR
        %****************************************************************
        function [f, P1] = espectro_amplitud_unilateral(app, x, Fs) % Parámetros a ingresar
            % Código referenciado de ejemplo de matlab
            Y = fft(x);
            P2 = abs(Y / app.N);
            P1 = P2(1:app.N/2+1);
            P1(2:end-1) = 2 * P1(2:end-1);
        
            f = Fs * (0:(app.N/2)) / app.N;
        
        end

        function actualizarGraficas(app)
            % Si no hay filtro diseñado (no hay constantes), no hacer nada
            if isempty(app.b_func) || isempty(app.a_func)
                return;
            end
        
            % 1. Obtener la respuesta compleja
            % Si queremos Hz, le pasamos app.Fs a freqz
            if app.EscalahorizontalDropDown.Value == "Hz"
                [H, w] = freqz(app.b_func, app.a_func);
                f = w*app.Fs/(2*pi); % vector de frecuencia, en Hz
                x_label = 'Frecuencia (Hz)';
            else
                [H, w] = freqz(app.b_func, app.a_func);
                f = w/pi; % Normalizada
                x_label = 'Frecuencia (rad/muestra)';
            end
        
            % 2. Procesar Magnitud según la escala vertical
            if app.EscalaverticalDropDown.Value == "dB"
                mag = 20*log10(abs(H));
                y_label = 'Magnitud (dB)';
            else
                mag = abs(H);
                y_label = 'Magnitud (Adimensional)';
            end
        
            % 3. Graficar Magnitud
            plot(app.magnitud, f, mag);
            ylabel(app.magnitud, y_label);
            xlabel(app.magnitud, x_label);
            grid(app.magnitud, "on");
        
            % 4. Graficar Fase (Siempre en UIAxes4)
            % Usamos unwrap para que no haya saltos de 360 grados
            plot(app.fase, f, unwrap(angle(H)));
            ylabel(app.fase, 'Fase (rad)');
            xlabel(app.fase, x_label);
            grid(app.fase, "on");
            
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            inicializar(app);
        end

        % Button pushed function: ConectarmicrocontroladorButton
        function ConectarmicrocontroladorButtonPushed(app, event)

            try
                app.conexion_uart = serialport("COM20", 115200);
                app.DuracindelasealEditField.Visible = "on";
                app.DuracindelasealEditFieldLabel.Visible ="on";
                app.RecopilardatosButton.Visible = "on";
                app.NohayconexinUARTLabel.Visible = "off";
                app.ConectarmicrocontroladorButton.Visible = "off";
            catch 
                % En el caso no haya conexión por el catch se ejecuta 
                app.NohayconexinUARTLabel.Visible = "on";
            end
          
        end

        % Button pushed function: CargardatosdeldispositivoButton
        function CargardatosdeldispositivoButtonPushed(app, event)
            % Deshabilitar todas las opciones de UART
            inicializar(app);

            [file,location] = uigetfile('*.mat'); % Selecciono la ruta
            % Si no se coloca lógica para cancelación de selección de
            % archivo el programa da error
            if isequal(file,0)
                return; % se canceló el proceso de selección
            end
            %necesito crear la ruta completa del archivo 
            fullpath = fullfile(location, file);
            app.datos = load(fullpath);
            app.signal = app.datos.x;
            app.Fs = app.datos.Fs;
            app.T = 1/app.Fs;

            % Se realiza el cálculo para hacer el vector tiempo
            app.N = length(app.datos.x);
            app.t = (0:app.N-1)/app.datos.Fs;
 
            % Graficar tanto en pestaña de entrada como de salida
            plot(app.graficaentrada, app.t, app.datos.x);
            plot(app.graficaoriginal2, app.t, app.datos.x);

            % Graficar el espectro unilateral cuando se cargan los datos
            % dependiendo del valor inicial que tiene el seleccionador
            [f, P1] = espectro_amplitud_unilateral(app, app.datos.x, app.datos.Fs);
            if app.stemorplot == 1
                stem(app.espectroentrada, f, P1);
            elseif app.stemorplot == 0
                plot(app.espectroentrada, f, P1);
            end

            % En este caso se asume que el seleccionador inicia en "plot"
            % del espectro pestaña salida
            plot(app.espectrooriginal2, f, P1);

        end

        % Button pushed function: ExportardatosButton
        function ExportardatosButtonPushed(app, event)
            [archivo, ruta] = uiputfile('*.csv'); % elijo la dirección del archivo

            if isequal(archivo,0)
                return; % se canceló el proceso
            end
            
            fullpath = fullfile(ruta, archivo); % Tomo la ruta completa del archivo
          
            data_a_guardar = [app.t(:), app.signal(:)];
             % (:) sirve para tomar los datos en columna y guardarlos así
            writematrix(data_a_guardar, fullpath); % Guardo las variables que quiero en la dirección completa


        end

        % Value changed function: RepresentacindegrficaDropDown
        function RepresentacindegrficaDropDownValueChanged(app, event)
            [f, P1] = espectro_amplitud_unilateral(app, app.signal, app.Fs);
            Selectedvalue = app.RepresentacindegrficaDropDown.Value;
            if Selectedvalue == "Stem"
                app.stemorplot = 1;
                stem(app.espectroentrada, f, P1);
            elseif Selectedvalue == "Plot"
                app.stemorplot = 0;
                plot(app.espectroentrada, f, P1);
            end
    
        end

        % Button down function: graficaentrada
        function graficaentradaButtonDown(app, event)
            
        end

        % Value changed function: RepresentacindegrficaDropDown_3
        function RepresentacindegrficaDropDown_3ValueChanged(app, event)
            % Seleccionador de gráfica del espectro en pestaña de salida
            [f, P1] = espectro_amplitud_unilateral(app, app.signal, app.Fs);
            Selectedvalue = app.RepresentacindegrficaDropDown_3.Value;
            if Selectedvalue == "Stem"
                stem(app.espectrooriginal2, f, P1);
            elseif Selectedvalue == "Plot"
                plot(app.espectrooriginal2, f, P1);
            end
        end

        % Button pushed function: RecopilardatosButton
        function RecopilardatosButtonPushed(app, event)

            app.Tdur = app.DuracindelasealEditField.Value;
            
            app.Fs = 1000;
            app.T = 1/app.Fs;

            app.N = app.Fs*app.Tdur;

            valores = zeros(app.N, 1);     % Creo el vector con la función zeros
            flush(app.conexion_uart); % Para limpiar los buffers

            for i = 1:app.N
                valores(i) = str2double(readline(app.conexion_uart));
            end
           
            app.signal = valores;

            app.t = (0:app.N-1)*(app.T/app.N); % calculo el vector de tiempo
            plot(app.graficaentrada, app.t, app.signal);

            [f, P1] = espectro_amplitud_unilateral(app, app.signal, app.Fs);
            if app.stemorplot == 1
                stem(app.espectroentrada, f, P1);
            elseif app.stemorplot == 0
                plot(app.espectroentrada, f, P1);
            end
        end

        % Value changed function: DuracindelasealEditField
        function DuracindelasealEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: ExportaramicrocontroladorButton
        function ExportaramicrocontroladorButtonPushed(app, event)
            % Se asume que los coeficientes obtenidos al crear los filtros no serán >= 1000000 ni
            % <= -100000. También se asume que coeficientes positivos menores a 0.0000005 o
            % coeficientes negativos mayores a -0.000005 se pueden considerar cero.
            % en esta parte del código se envían los coeficientes
            % y dependiendo del tamaño del número es la cantidad de
            % decimales que se muestran
               
            % coeficientes = [100.1234567,100.1234567,100.1234567,-10.1234567,100.1234567,100.1234567,100.1234567,100.1234567,100.1234567];
            % 
            % for k = 1:9
            %     coef = coeficientes(k);
            % 
            %     if (coef >= 100000 || coef <= -10000)
            %         dato = sprintf('%.1f', coef);
            %     elseif (coef >= 10000 || coef <= -1000)
            %         dato = sprintf('%.2f', coef);
            %     elseif (coef >= 1000 || coef <= -100)
            %         dato = sprintf('%.3f', coef);
            %     elseif (coef >= 100 || coef <= -10)
            %         dato = sprintf('%.4f', coef);
            %     elseif (coef >= 10 || coef < 0)
            %         dato = sprintf('%.5f', coef);
            %     else
            %         dato = sprintf('%.6f', coef);
            %     end
            % 
            %     write(app.conexion_uart, dato, 'char');
            %     pause(0.1);
            % end

            b = app.b_func;
            a = app.a_func;
            
            % Asegurar longitud mínima
            b = [b zeros(1, 4 - length(b))];
            a = [a zeros(1, 5 - length(a))];
            
            % Tomar solo lo necesario
            b = b(1:4);        % b0 b1 b2 b3
            a = a(2:5);        % a1 a2 a3 a4
            
            % Construir vector final
            coeficientes = [b 1 a];   % a0 = 1 en medio

            disp(coeficientes);

            
            % Enviar 9 coeficientes
            for k = 1:9
                coef = coeficientes(k);
            
                if (coef >= 100000 || coef <= -10000)
                    dato = sprintf('%.1f', coef);
                elseif (coef >= 10000 || coef <= -1000)
                    dato = sprintf('%.2f', coef);
                elseif (coef >= 1000 || coef <= -100)
                    dato = sprintf('%.3f', coef);
                elseif (coef >= 100 || coef <= -10)
                    dato = sprintf('%.4f', coef);
                elseif (coef >= 10 || coef < 0)
                    dato = sprintf('%.5f', coef);
                else
                    dato = sprintf('%.6f', coef);
                end
            
                write(app.conexion_uart, dato, 'char');
                pause(0.1);
            end
        end

        % Value changed function: TipodefiltroDropDown_2
        function TipodefiltroDropDown_2ValueChanged(app, event)
            FiltroValue = app.TipodefiltroDropDown_2.Value;
            if FiltroValue == "LPF (pasa bajas)"
                app.tipo_filtro = "low";
                app.Frecuenciadecortew2EditField.Visible = "off";
                app.Frecuenciadecortew2EditFieldLabel.Visible = "off";
            elseif FiltroValue == "HPF (pasa altas)"
                app.tipo_filtro = "high";
                app.Frecuenciadecortew2EditField.Visible = "off";
                app.Frecuenciadecortew2EditFieldLabel.Visible = "off";
            elseif FiltroValue == "BPF (pasa banda)"
                app.tipo_filtro = "bandpass";
                app.Frecuenciadecortew2EditField.Visible = "on";
                app.Frecuenciadecortew2EditFieldLabel.Visible = "on";
            elseif FiltroValue == "BSF (elimina banda)"
                app.tipo_filtro = "stop";
                app.Frecuenciadecortew2EditField.Visible = "on";
                app.Frecuenciadecortew2EditFieldLabel.Visible = "on";
            end            
        end

        % Button pushed function: GenerarfiltroButton
        function GenerarfiltroButtonPushed(app, event)

            wc_norm = 2*app.Frecuenciadecortew1EditField.Value*app.T;

            if app.tipo_filtro == "bandpass" || app.tipo_filtro == "stop"
                
                wc_norm_2 = 2 * app.Frecuenciadecortew2EditField.Value * app.T;
            
                if app.metodo == "butter"
                    [app.b_func, app.a_func] = butter(app.OrdenEditField.Value, [wc_norm wc_norm_2], app.tipo_filtro);
                    
                elseif app.metodo == "cheby1"
                    [app.b_func, app.a_func] = cheby1(app.OrdenEditField.Value, 0.5, [wc_norm wc_norm_2], app.tipo_filtro);
                end
            
            else
                
                if app.metodo == "butter"
                    [app.b_func, app.a_func] = butter(app.OrdenEditField.Value, wc_norm, app.tipo_filtro);
                    
                elseif app.metodo == "cheby1"
                    [app.b_func, app.a_func] = cheby1(app.OrdenEditField.Value, 0.5, wc_norm, app.tipo_filtro);
                end
            
            end

            actualizarGraficas(app);
        end

        % Value changed function: CategoraDropDown
        function CategoraDropDownValueChanged(app, event)
            value = app.CategoraDropDown.Value;
            if value == "Butterworth"
                app.metodo = "butter";
            elseif value == "Chebyshev_1"
                app.metodo = "cheby1";
            elseif value == "Chebyshev_2"
                app.metodo = "cheby2";
            elseif value == "Eliptica"
                app.metodo = "eliptica";
            elseif value == "Bessel"
                app.metodo = "bessel";
            end
        end

        % Value changed function: Frecuenciadecortew2EditField
        function Frecuenciadecortew2EditFieldValueChanged(app, event)
            value = app.Frecuenciadecortew2EditField.Value;
            if app.Frecuenciadecortew1EditField.Value < value
                app.Frecuenciadecortew2EditField.Value = app.Frecuenciadecortew1EditField.Value + 1;
                app.w2debesermayoraw1Label.Visible = "on";
            else
                app.w2debesermayoraw1Label.Visible = "off";   
            end
        end

        % Value changed function: EscalahorizontalDropDown
        function EscalahorizontalDropDownValueChanged(app, event)
            actualizarGraficas(app);
        end

        % Value changed function: EscalaverticalDropDown
        function EscalaverticalDropDownValueChanged(app, event)
            actualizarGraficas(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntradaTab = uitab(app.TabGroup);
            app.EntradaTab.Title = 'Entrada';
            app.espectroentrada = uiaxes(app.EntradaTab);
            title(app.espectroentrada, 'Espectro unilateral señal de entrada')
            xlabel(app.espectroentrada, 'Hz')
            ylabel(app.espectroentrada, 'Amplitud')
            zlabel(app.espectroentrada, 'Z')
            app.graficaentrada = uiaxes(app.EntradaTab);
            title(app.graficaentrada, 'Señal de entrada')
            xlabel(app.graficaentrada, 't (s)')
            ylabel(app.graficaentrada, 'Amplitud')
            zlabel(app.graficaentrada, 'Z')
            app.graficaentrada.ButtonDownFcn = createCallbackFcn(app, @graficaentradaButtonDown, true);
            app.RepresentacindegrficaDropDownLabel = uilabel(app.EntradaTab);
            app.RepresentacindegrficaDropDownLabel.Text = 'Representación de gráfica';
            app.RepresentacindegrficaDropDown = uidropdown(app.EntradaTab);
            app.RepresentacindegrficaDropDown.Items = {'Plot', 'Stem'};
            app.RepresentacindegrficaDropDown.ValueChangedFcn = createCallbackFcn(app, @RepresentacindegrficaDropDownValueChanged, true);
            app.RepresentacindegrficaDropDown.Value = 'Plot';
            app.Panel = uipanel(app.EntradaTab);
            app.ConectarmicrocontroladorButton = uibutton(app.Panel, 'push');
            app.ConectarmicrocontroladorButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarmicrocontroladorButtonPushed, true);
            app.ConectarmicrocontroladorButton.Text = 'Conectar microcontrolador';
            app.NohayconexinUARTLabel = uilabel(app.Panel);
            app.NohayconexinUARTLabel.Text = 'No hay conexión UART';
            app.DuracindelasealEditFieldLabel = uilabel(app.Panel);
            app.DuracindelasealEditFieldLabel.Text = 'Duración de la señal';
            app.DuracindelasealEditField = uieditfield(app.Panel, 'numeric');
            app.DuracindelasealEditField.Limits = [0 5];
            app.DuracindelasealEditField.ValueChangedFcn = createCallbackFcn(app, @DuracindelasealEditFieldValueChanged, true);
            app.DuracindelasealEditField.Value = 1;
            app.RecopilardatosButton = uibutton(app.Panel, 'push');
            app.RecopilardatosButton.ButtonPushedFcn = createCallbackFcn(app, @RecopilardatosButtonPushed, true);
            app.RecopilardatosButton.Text = {'Recopilar '; 'datos'};
            app.Panel_2 = uipanel(app.EntradaTab);
            app.CargardatosdeldispositivoButton = uibutton(app.Panel_2, 'push');
            app.CargardatosdeldispositivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosdeldispositivoButtonPushed, true);
            app.CargardatosdeldispositivoButton.Text = 'Cargar datos del dispositivo';
            app.FiltroTab = uitab(app.TabGroup);
            app.FiltroTab.Title = 'Filtro';
            app.magnitud = uiaxes(app.FiltroTab);
            title(app.magnitud, 'Magnitud')
            xlabel(app.magnitud, 'X')
            ylabel(app.magnitud, 'Y')
            zlabel(app.magnitud, 'Z')
            app.fase = uiaxes(app.FiltroTab);
            title(app.fase, 'Fase')
            xlabel(app.fase, 'X')
            ylabel(app.fase, 'Y')
            zlabel(app.fase, 'Z')
            app.DiseodefiltroPanel = uipanel(app.FiltroTab);
            app.DiseodefiltroPanel.Title = 'Diseño de filtro';
            app.Frecuenciadecortew1EditFieldLabel = uilabel(app.DiseodefiltroPanel);
            app.Frecuenciadecortew1EditFieldLabel.Text = 'Frecuencia de corte w1';
            app.Frecuenciadecortew1EditField = uieditfield(app.DiseodefiltroPanel, 'numeric');
            app.TipodefiltroDropDown_2Label = uilabel(app.DiseodefiltroPanel);
            app.TipodefiltroDropDown_2Label.Text = 'Tipo de filtro';
            app.TipodefiltroDropDown_2 = uidropdown(app.DiseodefiltroPanel);
            app.TipodefiltroDropDown_2.Items = {'LPF (pasa bajas)', 'HPF (pasa altas)', 'BPF (pasa banda)', 'BSF (elimina banda)'};
            app.TipodefiltroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDown_2ValueChanged, true);
            app.TipodefiltroDropDown_2.Value = 'LPF (pasa bajas)';
            app.GraficarLabel = uilabel(app.DiseodefiltroPanel);
            app.GraficarLabel.Text = 'Graficar';
            app.EscalaverticalDropDownLabel = uilabel(app.DiseodefiltroPanel);
            app.EscalaverticalDropDownLabel.Text = 'Escala vertical';
            app.EscalaverticalDropDown = uidropdown(app.DiseodefiltroPanel);
            app.EscalaverticalDropDown.Items = {'dB', 'Adimensional'};
            app.EscalaverticalDropDown.ValueChangedFcn = createCallbackFcn(app, @EscalaverticalDropDownValueChanged, true);
            app.EscalaverticalDropDown.Value = 'dB';
            app.EscalahorizontalDropDownLabel = uilabel(app.DiseodefiltroPanel);
            app.EscalahorizontalDropDownLabel.Text = 'Escala horizontal';
            app.EscalahorizontalDropDown = uidropdown(app.DiseodefiltroPanel);
            app.EscalahorizontalDropDown.Items = {'rad/muestra', 'Hz'};
            app.EscalahorizontalDropDown.ValueChangedFcn = createCallbackFcn(app, @EscalahorizontalDropDownValueChanged, true);
            app.EscalahorizontalDropDown.Value = 'rad/muestra';
            app.OrdenEditFieldLabel = uilabel(app.DiseodefiltroPanel);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.DiseodefiltroPanel, 'numeric');
            app.GenerarfiltroButton = uibutton(app.DiseodefiltroPanel, 'push');
            app.GenerarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarfiltroButtonPushed, true);
            app.GenerarfiltroButton.Text = 'Generar filtro';
            app.Frecuenciadecortew2EditFieldLabel = uilabel(app.DiseodefiltroPanel);
            app.Frecuenciadecortew2EditFieldLabel.Text = 'Frecuencia de corte w2';
            app.Frecuenciadecortew2EditField = uieditfield(app.DiseodefiltroPanel, 'numeric');
            app.Frecuenciadecortew2EditField.ValueChangedFcn = createCallbackFcn(app, @Frecuenciadecortew2EditFieldValueChanged, true);
            app.CategoraDropDownLabel = uilabel(app.DiseodefiltroPanel);
            app.CategoraDropDownLabel.Text = 'Categoría';
            app.CategoraDropDown = uidropdown(app.DiseodefiltroPanel);
            app.CategoraDropDown.Items = {'Butterworth', 'Chebyshev_1', 'Chebyshev_2', 'Eliptica', 'Bessel'};
            app.CategoraDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoraDropDownValueChanged, true);
            app.CategoraDropDown.Value = 'Butterworth';
            app.w2debesermayoraw1Label = uilabel(app.DiseodefiltroPanel);
            app.w2debesermayoraw1Label.Text = 'w2 debe ser mayor a w1';
            app.ExportaramicrocontroladorButton = uibutton(app.FiltroTab, 'push');
            app.ExportaramicrocontroladorButton.ButtonPushedFcn = createCallbackFcn(app, @ExportaramicrocontroladorButtonPushed, true);
            app.ExportaramicrocontroladorButton.Text = {'Exportar a '; 'microcontrolador'};
            app.RespuestaenfercuenciaLabel = uilabel(app.FiltroTab);
            app.RespuestaenfercuenciaLabel.Text = 'Respuesta en fercuencia';
            app.ButtonGroup = uibuttongroup(app.FiltroTab);
            app.FiltroFIRButton = uiradiobutton(app.ButtonGroup);
            app.FiltroFIRButton.Text = 'Filtro FIR';
            app.FiltroFIRButton.Value = true;
            app.FiltroIIRButton = uiradiobutton(app.ButtonGroup);
            app.FiltroIIRButton.Text = 'Filtro IIR';
            app.GuardarvariablesdelfiltroButton = uibutton(app.FiltroTab, 'push');
            app.GuardarvariablesdelfiltroButton.Text = {'Guardar variables '; 'del filtro'};
            app.SalidaTab = uitab(app.TabGroup);
            app.SalidaTab.Title = 'Salida';
            app.graficaoriginal2 = uiaxes(app.SalidaTab);
            title(app.graficaoriginal2, 'Señal original')
            xlabel(app.graficaoriginal2, 't (s)')
            ylabel(app.graficaoriginal2, 'Amplitud')
            zlabel(app.graficaoriginal2, 'Z')
            app.espectrooriginal2 = uiaxes(app.SalidaTab);
            title(app.espectrooriginal2, 'Espectro unilateral señal original')
            xlabel(app.espectrooriginal2, 'Hz')
            ylabel(app.espectrooriginal2, 'Amplitud')
            zlabel(app.espectrooriginal2, 'Z')
            app.UIAxes2_2 = uiaxes(app.SalidaTab);
            title(app.UIAxes2_2, 'Señal procesada')
            xlabel(app.UIAxes2_2, 't (s)')
            ylabel(app.UIAxes2_2, 'Amplitud')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.SalidaTab);
            title(app.UIAxes2_3, 'Espectro unilateral señal procesada')
            xlabel(app.UIAxes2_3, 'Hz')
            ylabel(app.UIAxes2_3, 'Amplitud')
            zlabel(app.UIAxes2_3, 'Z')
            app.ExportardatosButton = uibutton(app.SalidaTab, 'push');
            app.ExportardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosButtonPushed, true);
            app.ExportardatosButton.Text = 'Exportar datos';
            app.RepresentacindegrficaDropDown_2Label = uilabel(app.SalidaTab);
            app.RepresentacindegrficaDropDown_2Label.Text = 'Representación de gráfica';
            app.RepresentacindegrficaDropDown_2 = uidropdown(app.SalidaTab);
            app.RepresentacindegrficaDropDown_2.Items = {'Plot', 'Stem'};
            app.RepresentacindegrficaDropDown_2.Value = 'Plot';
            app.RepresentacindegrficaDropDown_3Label = uilabel(app.SalidaTab);
            app.RepresentacindegrficaDropDown_3Label.Text = 'Representación de gráfica';
            app.RepresentacindegrficaDropDown_3 = uidropdown(app.SalidaTab);
            app.RepresentacindegrficaDropDown_3.Items = {'Plot', 'Stem'};
            app.RepresentacindegrficaDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RepresentacindegrficaDropDown_3ValueChanged, true);
            app.RepresentacindegrficaDropDown_3.Value = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Unidad_procesamiento_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end